classdef RectSampleSize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        HEditFieldLabel  matlab.ui.control.Label
        HIdxEdit         matlab.ui.control.NumericEditField
        KEditFieldLabel  matlab.ui.control.Label
        KIdxEdit         matlab.ui.control.NumericEditField
        LEditFieldLabel  matlab.ui.control.Label
        LIdxEdit         matlab.ui.control.NumericEditField
        WidthALabel      matlab.ui.control.Label
        widthEdit        matlab.ui.control.NumericEditField
        ThicknessALabel  matlab.ui.control.Label
        thicknessEdit    matlab.ui.control.NumericEditField
        OKButton         matlab.ui.control.Button
        Image            matlab.ui.control.Image
    end

    
    properties (Access = private)
        callingApp % main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, hkl, width, thickness)
            % assign main app object
%             app.callingApp = mainapp;
            
            app.HIdxEdit.Value = 0;
            app.KIdxEdit.Value = 0;
            app.LIdxEdit.Value = 0;
            
            app.WidthALabel.Text = ['Width (', char(197), ')'];
            app.widthEdit.Value = 10;
            app.ThicknessALabel.Text = ['Thickness (', char(197), ')'];
            app.thicknessEdit.Value = 10;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HEditFieldLabel = uilabel(app.UIFigure);
            app.HEditFieldLabel.Text = 'H';
            app.HIdxEdit = uieditfield(app.UIFigure, 'numeric');
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KIdxEdit = uieditfield(app.UIFigure, 'numeric');
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.LIdxEdit = uieditfield(app.UIFigure, 'numeric');
            app.WidthALabel = uilabel(app.UIFigure);
            app.WidthALabel.Text = 'Width (A)';
            app.widthEdit = uieditfield(app.UIFigure, 'numeric');
            app.ThicknessALabel = uilabel(app.UIFigure);
            app.ThicknessALabel.Text = 'Thickness (A)';
            app.thicknessEdit = uieditfield(app.UIFigure, 'numeric');
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.Text = 'OK';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'specimen-1.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RectSampleSize(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end